classdef MPI_Simulate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        Button_3                matlab.ui.control.Button
        Panel_3                 matlab.ui.container.Panel
        Button_2                matlab.ui.control.Button
        Button                  matlab.ui.control.Button
        start_simulation        matlab.ui.control.Button
        Panel_2                 matlab.ui.container.Panel
        ALabel                  matlab.ui.control.Label
        kHzLabel                matlab.ui.control.Label
        excitation_amp          matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
        excitation_fre          matlab.ui.control.NumericEditField
        Label_7                 matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        usLabel                 matlab.ui.control.Label
        nmLabel                 matlab.ui.control.Label
        response_model          matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        relaxation_time         matlab.ui.control.NumericEditField
        EditField_2Label        matlab.ui.control.Label
        particle_diameter       matlab.ui.control.NumericEditField
        Label_6                 matlab.ui.control.Label
        figure_magnetic_change  matlab.ui.control.UIAxes
        figure_excitation       matlab.ui.control.UIAxes
        figure_magnetic_charac  matlab.ui.control.UIAxes
        figure_signal           matlab.ui.control.UIAxes
        figure_harmonic         matlab.ui.control.UIAxes
        Tab2                    matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function [Excita_field, Mag_H, Mag_H_dif, harmonic_amp, sample_fre, time_sequence] = func_gene_signal(app, Exci_fre, Exci_amp, patc_diamet, relax_time, resp_model)
%             addpath(fullfile(pwd, 'tools'));
            rehash;
            sample_fre = 1e8;       % 这里预定义采样率 100M
            time_sequence = 0:1/sample_fre:5*1/(25e3);  
            Excita_field = Exci_amp * cos(2*pi*Exci_fre*time_sequence);
            if length(relax_time) == 1
                Mag_H = zeros(length(patc_diamet), length(Excita_field));
                Mag_H_dif = zeros(length(patc_diamet), length(Excita_field)-1);
                for i = 1:length(patc_diamet)
                    Mag_H(i,:) = func_calcu_mag(Excita_field, patc_diamet(i),sample_fre,relax_time,Exci_fre,resp_model);
                    Mag_H_dif(i,:) = diff(Mag_H(i,:));
                end
            else
                Mag_H = zeros(length(relax_time), length(Excita_field));
                Mag_H_dif = zeros(length(relax_time), length(Excita_field)-1);
                for i = 1:length(relax_time)
                    Mag_H(i,:) = func_calcu_mag(Excita_field, patc_diamet,sample_fre,relax_time(i),Exci_fre,resp_model);
                    Mag_H_dif(i,:) = diff(Mag_H(i,:));
                end
            end
            
            
            fre_transform = signal_fft(app, Mag_H_dif, size(Mag_H_dif,2));
            fre_index = sample_fre*(0:size(Mag_H_dif,2)/2)/size(Mag_H_dif,2);
            show_harmonic_num = 9;
            harmonic_amp = fre_transform(:,(find(ismember(fre_index/Exci_fre, 1:show_harmonic_num))));
%             rmpath(fullfile(pwd, 'tools'));
        end
        
        function results = signal_fft(app, signal, L)
%             Length_signal = length(signal);
            out_ft = fft(signal, [], 2);
            Amp2 = abs(out_ft);
            Amp1 = Amp2(:, 1:L/2+1);
            Amp1(:,2:end-1) = 2*Amp1(:,2:end-1);
            results = Amp1;
        end
        
        function figure_plot(app, Excita_field, harmonic_amp, time_sequence, num_point_per_period, Mag_H_plot, Mag_H_dif_plot, legend_str, title_str)      
            figure(1);
            subplot(2,2,1);
            plot(Excita_field(1:3*num_point_per_period), abs(Mag_H_dif_plot), 'LineWidth', 1.0);
            legend(legend_str);
            title("磁化特性");
            subplot(2,2,2);
            plot(time_sequence(1:3*num_point_per_period), Mag_H_plot,'LineWidth', 1.0);
            legend(legend_str);
            title("磁化变化");
            subplot(2,2,3);
            bar(harmonic_amp', 'EdgeColor', 'none', 'BarWidth', 0.1);
            legend(legend_str);
            title("谐波强度");
            subplot(2,2,4);
            plot(time_sequence(1:3*num_point_per_period), Mag_H_dif_plot, 'LineWidth', 1.0);
            legend(legend_str);
            title("响应信号");
            sgtitle(title_str);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: start_simulation
        function start_simulationPushed(app, event)

            Excited_Amplitute = app.excitation_amp.Value * 1e-3;  % 灵敏度
            Excited_Fre = app.excitation_fre.Value * 1e3;  % 转化为Hz
            relax_time = app.relaxation_time.Value * 1e-6;  % 单位 微秒
            resp_model = app.response_model.Value;
            patcl_diamet = app.particle_diameter.Value * 1e-9;     % 单位 nm
            [Excita_field, Mag_H, Mag_H_dif, harmonic_amp, sample_fre, time_sequence] = func_gene_signal(app, Excited_Fre, Excited_Amplitute, patcl_diamet, relax_time, resp_model);
            ax_excitation = app.figure_excitation;
            ax_magnetic_charac = app.figure_magnetic_charac;
            ax_magnetic_change = app.figure_magnetic_change;
            ax_signal = app.figure_signal;
            ax_harmonic = app.figure_harmonic;
            
            num_point_per_period = floor(sample_fre/Excited_Fre);
            %% 绘制图像 
            plot(ax_excitation, Excita_field(1:3*num_point_per_period), time_sequence(1:3*num_point_per_period), 'LineWidth',1.0);
            
            Mag_H_plot = Mag_H(:,1:3*num_point_per_period)/(max(max(abs(Mag_H(:,1:3*num_point_per_period)))));
            Mag_H_dif_plot = Mag_H_dif(:,1:3*num_point_per_period)/(max(max(abs(Mag_H_dif(:,1:3*num_point_per_period)))));
            plot(ax_magnetic_charac, Excita_field(1:3*num_point_per_period), Mag_H_plot, 'LineWidth', 1.0);
            ax_magnetic_charac.NextPlot = 'add';
            plot(ax_magnetic_charac, Excita_field(1:3*num_point_per_period), abs(Mag_H_dif_plot), 'LineWidth', 1.0);
            ax_magnetic_charac.NextPlot = 'replace';
            
            plot(ax_magnetic_change, time_sequence(1:3*num_point_per_period), Mag_H(1:3*num_point_per_period),'LineWidth', 1.0);
            plot(ax_signal, time_sequence(1:3*num_point_per_period), Mag_H_dif(1:3*num_point_per_period), 'LineWidth', 1.0);
            bar(ax_harmonic, harmonic_amp, 'EdgeColor','b', 'BarWidth', 0.01);
        end

        % Button pushed function: Button
        function ButtonPushed_diameter(app, event)
            Excited_Amplitute = app.excitation_amp.Value * 1e-3;  % 灵敏度
            Excited_Fre = app.excitation_fre.Value * 1e3;  % 转化为Hz
            relax_time = app.relaxation_time.Value * 1e-6;  % 单位 微秒
            resp_model = app.response_model.Value;
            patcl_diamet = [20, 25, 30, 35] * 1e-9;     % 单位 nm
            [Excita_field, Mag_H, Mag_H_dif, harmonic_amp, sample_fre, time_sequence] = func_gene_signal(app, Excited_Fre, Excited_Amplitute, patcl_diamet, relax_time, resp_model);
            
            num_point_per_period = floor(sample_fre/Excited_Fre);
            Mag_H_plot = Mag_H(:,1:3*num_point_per_period)/(max(max(abs(Mag_H(:,1:3*num_point_per_period)))));
            Mag_H_dif_plot = Mag_H_dif(:,1:3*num_point_per_period)/(max(max(abs(Mag_H_dif(:,1:3*num_point_per_period)))));
            legend_str = ["20nm","25nm","30nm","35nm"];
            title_str = "不同粒径，信号响应对比";
            figure_plot(app, Excita_field, harmonic_amp, time_sequence, num_point_per_period, Mag_H_plot, Mag_H_dif_plot, legend_str, title_str);

        end

        % Button pushed function: Button_2
        function ButtonPushed_rexalation(app, event)
            Excited_Amplitute = app.excitation_amp.Value * 1e-3;  % 灵敏度
            Excited_Fre = app.excitation_fre.Value * 1e3;  % 转化为Hz
            relax_time = [0.5, 1, 1.5, 2] * 1e-6;  % 单位 微秒
            resp_model = app.response_model.Value;
            patcl_diamet = app.particle_diameter.Value * 1e-9;     % 单位 nm
            [Excita_field, Mag_H, Mag_H_dif, harmonic_amp, sample_fre, time_sequence] = func_gene_signal(app, Excited_Fre, Excited_Amplitute, patcl_diamet, relax_time, resp_model);
            
            num_point_per_period = floor(sample_fre/Excited_Fre);
            Mag_H_plot = Mag_H(:,1:3*num_point_per_period)/(max(max(abs(Mag_H(:,1:3*num_point_per_period)))));
            Mag_H_dif_plot = Mag_H_dif(:,1:3*num_point_per_period)/(max(max(abs(Mag_H_dif(:,1:3*num_point_per_period)))));  
            
            legend_str = ["0.5us","1us","1.5","2us"];
            title_str = "不同弛豫时间，信号响应对比";
            figure_plot(app, Excita_field, harmonic_amp, time_sequence, num_point_per_period, Mag_H_plot, Mag_H_dif_plot, legend_str, title_str);
          
        end

        % Button pushed function: Button_3
        function ButtonPushed_claWind(app, event)
            cla(app.figure_excitation);
            cla(app.figure_magnetic_charac);
            cla(app.figure_magnetic_change);
            cla(app.figure_signal);
            cla(app.figure_harmonic);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '粒子响应特性';
            app.figure_harmonic = uiaxes(app.Tab);
            title(app.figure_harmonic, '谐波信号')
            xlabel(app.figure_harmonic, 'f/f_0')
            ylabel(app.figure_harmonic, 'Amp')
            zlabel(app.figure_harmonic, 'Z')
            app.figure_harmonic.YTick = [];
            app.figure_signal = uiaxes(app.Tab);
            title(app.figure_signal, '响应信号')
            xlabel(app.figure_signal, 'Time')
            ylabel(app.figure_signal, 'Amp')
            zlabel(app.figure_signal, 'Z')
            app.figure_signal.YTick = [];
            app.figure_magnetic_charac = uiaxes(app.Tab);
            title(app.figure_magnetic_charac, '磁化特性曲线')
            xlabel(app.figure_magnetic_charac, 'H')
            ylabel(app.figure_magnetic_charac, 'dM/dH & M')
            zlabel(app.figure_magnetic_charac, 'Z')
            app.figure_magnetic_charac.XTick = 0;
            app.figure_magnetic_charac.YTick = 0;
            app.figure_excitation = uiaxes(app.Tab);
            title(app.figure_excitation, '激励信号')
            xlabel(app.figure_excitation, 'Amp')
            ylabel(app.figure_excitation, 'Time')
            zlabel(app.figure_excitation, 'Z')
            app.figure_magnetic_change = uiaxes(app.Tab);
            title(app.figure_magnetic_change, '磁化变化')
            xlabel(app.figure_magnetic_change, 'Time')
            ylabel(app.figure_magnetic_change, 'M')
            zlabel(app.figure_magnetic_change, 'Z')
            app.figure_magnetic_change.XTick = [0 0.5 1];
            app.figure_magnetic_change.YTick = 0;
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '粒子参数';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '粒径';
            app.particle_diameter = uieditfield(app.Panel, 'numeric');
            app.particle_diameter.Value = 25;
            app.EditField_2Label = uilabel(app.Panel);
            app.EditField_2Label.Text = '弛豫时间';
            app.relaxation_time = uieditfield(app.Panel, 'numeric');
            app.relaxation_time.Value = 1;
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '响应模型';
            app.response_model = uidropdown(app.Panel);
            app.response_model.Items = {'Langevin', 'Step', 'Linear'};
            app.response_model.Value = 'Langevin';
            app.nmLabel = uilabel(app.Panel);
            app.nmLabel.Text = 'nm';
            app.usLabel = uilabel(app.Panel);
            app.usLabel.Text = 'us';
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '磁场参数';
            app.Label_7 = uilabel(app.Panel_2);
            app.Label_7.Text = '激励频率';
            app.excitation_fre = uieditfield(app.Panel_2, 'numeric');
            app.excitation_fre.Value = 25;
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '激励幅值';
            app.excitation_amp = uieditfield(app.Panel_2, 'numeric');
            app.excitation_amp.Value = 16;
            app.kHzLabel = uilabel(app.Panel_2);
            app.kHzLabel.Text = 'kHz';
            app.ALabel = uilabel(app.Panel_2);
            app.ALabel.Text = 'A';
            app.start_simulation = uibutton(app.Tab, 'push');
            app.start_simulation.ButtonPushedFcn = createCallbackFcn(app, @start_simulationPushed, true);
            app.start_simulation.Text = '开始仿真';
            app.Panel_3 = uipanel(app.Tab);
            app.Panel_3.Title = '粒子响应对比';
            app.Button = uibutton(app.Panel_3, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_diameter, true);
            app.Button.Text = '不同粒径响应差异';
            app.Button_2 = uibutton(app.Panel_3, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_rexalation, true);
            app.Button_2.Text = '不同弛豫时间响应差异';
            app.Button_3 = uibutton(app.Tab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed_claWind, true);
            app.Button_3.Text = '初始化';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MPI_Simulate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end